classdef app1 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                      matlab.ui.Figure
        RozkadDropDownLabel           matlab.ui.control.Label
        RozkadDropDown                matlab.ui.control.DropDown
        GenerujdaneButton             matlab.ui.control.Button
        MetodaDropDownLabel           matlab.ui.control.Label
        MetodaDropDown                matlab.ui.control.DropDown
        InputDataTable                matlab.ui.control.Table
        OutputDataTable               matlab.ui.control.Table
        LiczbakryteriumDropDownLabel  matlab.ui.control.Label
        LiczbakryteriumDropDown       matlab.ui.control.DropDown
        WczytajdaneButton             matlab.ui.control.Button
        RozmiarprbkiEditFieldLabel    matlab.ui.control.Label
        RozmiarProbkiEditField        matlab.ui.control.NumericEditField
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: GenerujdaneButton
        function GenerujdaneButtonPushed(app, event)
            distribution = app.RozkadDropDown.Value;
            no_samples = app.RozmiarProbkiEditField.Value;
            no_criterion = str2num(app.LiczbakryteriumDropDown.Value);
            switch distribution
                case 'Normalny'
                    prompt = {'Parameter mu:', 'Parameter sigma:'};
                    dlgtitle = 'Input';
                    dims = [1 35];
                    definput = {'1', '0'};
                    answer = inputdlg(prompt, dlgtitle, dims,definput);
                    mu = str2double(answer{1});
                    sigma = str2double(answer{2});
                    map = containers.Map({'mu', 'sigma'}, [mu, sigma]);
                    generate_data('Normalny', no_samples, no_criterion, map);
                case 'Eksponencjalny'
                    prompt = {'Parameter mu:'};
                    dlgtitle = 'Input';
                    dims = [1 35];
                    definput = {'1'};
                    answer = inputdlg(prompt, dlgtitle, dims,definput);
                    mu = str2double(answer{1});
                    map = containers.Map({'mu'}, [mu]);
                    generate_data('Eksponencjalny', no_samples, no_criterion, map);
                case 'Jednostajny'
                    generate_data('Jednostajny', no_samples, no_criterion);
            end
            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'UI Figure';
            app.RozkadDropDownLabel = uilabel(app.UIFigure);
            app.RozkadDropDownLabel.Text = 'Rozkład';
            app.RozkadDropDown = uidropdown(app.UIFigure);
            app.RozkadDropDown.Items = {'Normalny', 'Eksponencjalny', 'Jednostajny'};
            app.RozkadDropDown.Value = 'Normalny';
            app.GenerujdaneButton = uibutton(app.UIFigure, 'push');
            app.GenerujdaneButton.ButtonPushedFcn = createCallbackFcn(app, @GenerujdaneButtonPushed, true);
            app.GenerujdaneButton.Text = 'Generuj dane';
            app.MetodaDropDownLabel = uilabel(app.UIFigure);
            app.MetodaDropDownLabel.Text = 'Metoda';
            app.MetodaDropDown = uidropdown(app.UIFigure);
            app.InputDataTable = uitable(app.UIFigure);
            app.InputDataTable.ColumnName = {'Column 1'; 'Column 2'; 'Column 3'; 'Column 4'};
            app.InputDataTable.RowName = {};
            app.OutputDataTable = uitable(app.UIFigure);
            app.OutputDataTable.ColumnName = {'Column 1'; 'Column 2'; 'Column 3'; 'Column 4'};
            app.OutputDataTable.RowName = {};
            app.LiczbakryteriumDropDownLabel = uilabel(app.UIFigure);
            app.LiczbakryteriumDropDownLabel.Text = 'Liczba kryterium';
            app.LiczbakryteriumDropDown = uidropdown(app.UIFigure);
            app.LiczbakryteriumDropDown.Items = {'2', '3', '4', '5', '10'};
            app.LiczbakryteriumDropDown.Value = '2';
            app.WczytajdaneButton = uibutton(app.UIFigure, 'push');
            app.WczytajdaneButton.Text = {'Wczytaj'; 'dane'};
            app.RozmiarprbkiEditFieldLabel = uilabel(app.UIFigure);
            app.RozmiarprbkiEditFieldLabel.Text = {'Rozmiar'; 'próbki'};
            app.RozmiarProbkiEditField = uieditfield(app.UIFigure, 'numeric');
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = app1

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end